classdef R2RContinuousProcessSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulateButton                  matlab.ui.control.Button
        SiM                             matlab.ui.control.Image
        KU                              matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        OpTension                       matlab.ui.control.NumericEditField
        FixedStepsecLabel_6             matlab.ui.control.Label
        WebSpeed                        matlab.ui.control.NumericEditField
        FixedStepsecLabel_5             matlab.ui.control.Label
        WebThickness                    matlab.ui.control.NumericEditField
        FixedStepsecLabel_4             matlab.ui.control.Label
        WebWidth                        matlab.ui.control.NumericEditField
        FixedStepsecLabel_3             matlab.ui.control.Label
        StopTime                        matlab.ui.control.NumericEditField
        FixedStepsecLabel_2             matlab.ui.control.Label
        FixedStep                       matlab.ui.control.NumericEditField
        FixedStepsecLabel               matlab.ui.control.Label
        TheNumberofSpansEditField       matlab.ui.control.NumericEditField
        TheNumberofSpansEditFieldLabel  matlab.ui.control.Label
        TheNumberofSpansSlider          matlab.ui.control.Slider
        TabGroup                        matlab.ui.container.TabGroup
        Span                            matlab.ui.container.Tab
        TensionDisturbanceNEditField    matlab.ui.control.EditField
        TensionDisturbanceNEditFieldLabel  matlab.ui.control.Label
        VelocityDisturbancemsEditField  matlab.ui.control.EditField
        VelocityDisturbancemsEditFieldLabel  matlab.ui.control.Label
        SpanLengthmEditField            matlab.ui.control.NumericEditField
        SpanLengthmEditFieldLabel       matlab.ui.control.Label
        ThermalStrainEditField          matlab.ui.control.NumericEditField
        ThermalStrainEditFieldLabel     matlab.ui.control.Label
        EquivalentYoungsModulusGPaEditField  matlab.ui.control.NumericEditField
        EquivalentYoungsModulusGPaEditFieldLabel  matlab.ui.control.Label
        InletYoungsModulusGPaEditField  matlab.ui.control.NumericEditField
        InletYoungsModulusGPaEditFieldLabel  matlab.ui.control.Label
        SpanInfo                        matlab.ui.control.Image
        Panel_2                         matlab.ui.container.Panel
        RegisterError                   matlab.ui.control.UIAxes
        TensionTransfer                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        simulModel; % Description
    end
    
    methods (Access = private)
        
        function [] = editSpan(app, spanLength)
            app.SpanInfo.ImageSource = append(string(spanLength),'.png');
            for i = 1:length(app.Span)
                delete(app.Span(i))
%                 length(app.Span)
%                 eval(append('delete(app.Span_', string(i), ')'))
            end

            for i = 1:spanLength
                app.Span(i) = uitab(app.TabGroup);
                app.Span(i).Title = append('Span ', string(i));
                
                app.InletYoungsModulusGPaEditFieldLabel(i) = uilabel(app.Span(i));
                app.InletYoungsModulusGPaEditFieldLabel(i).Position = [15 142 208 22];
                app.InletYoungsModulusGPaEditFieldLabel(i).Text = 'Inlet Young''s Modulus [GPa]';
                
                app.InletYoungsModulusGPaEditField(i) = uieditfield(app.Span(i), 'numeric');
                app.InletYoungsModulusGPaEditField(i).Position = [239 142 100 22];
                app.InletYoungsModulusGPaEditField(i).Value = 1.883;
                
                app.EquivalentYoungsModulusGPaEditFieldLabel(i) = uilabel(app.Span(i));
                app.EquivalentYoungsModulusGPaEditFieldLabel(i).Position = [15 109 209 22];
                app.EquivalentYoungsModulusGPaEditFieldLabel(i).Text = 'Equivalent Young''s Modulus [GPa]';
                
                app.EquivalentYoungsModulusGPaEditField(i) = uieditfield(app.Span(i), 'numeric');
                app.EquivalentYoungsModulusGPaEditField(i).Position = [239 109 100 22];
                app.EquivalentYoungsModulusGPaEditField(i).Value = 1.883;

                app.ThermalStrainEditFieldLabel(i) = uilabel(app.Span(i));
                app.ThermalStrainEditFieldLabel(i).Position = [15 76 209 22];
                app.ThermalStrainEditFieldLabel(i).Text = 'Thermal Strain';
                
                app.ThermalStrainEditField(i) = uieditfield(app.Span(i), 'numeric');
                app.ThermalStrainEditField(i).Position = [239 76 100 22];
                app.ThermalStrainEditField(i).Value = 0;
                
                app.SpanLengthmEditFieldLabel(i) = uilabel(app.Span(i));
                app.SpanLengthmEditFieldLabel(i).Position = [15 175 209 22];
                app.SpanLengthmEditFieldLabel(i).Text = 'Span Length [m]';
                
                app.SpanLengthmEditField(i) = uieditfield(app.Span(i), 'numeric');
                app.SpanLengthmEditField(i).Position = [239 175 100 22];
                app.SpanLengthmEditField(i).Value = 3;
                
                app.VelocityDisturbancemsEditFieldLabel(i) = uilabel(app.Span(i));
                app.VelocityDisturbancemsEditFieldLabel(i).Position = [15 43 209 22];
                app.VelocityDisturbancemsEditFieldLabel(i).Text = 'Velocity Disturbance [m/s]';
                
                app.VelocityDisturbancemsEditField(i) = uieditfield(app.Span(i), 'text');
                app.VelocityDisturbancemsEditField(i).HorizontalAlignment = 'right';
                app.VelocityDisturbancemsEditField(i).Position = [239 43 100 22];
                app.VelocityDisturbancemsEditField(i).Value = '0';
                
                app.TensionDisturbanceNEditFieldLabel(i) = uilabel(app.Span(i));
                app.TensionDisturbanceNEditFieldLabel(i).Position = [15 10 209 22];
                app.TensionDisturbanceNEditFieldLabel(i).Text = 'Tension Disturbance [N]';
                
                app.TensionDisturbanceNEditField(i) = uieditfield(app.Span(i), 'text');
                app.TensionDisturbanceNEditField(i).HorizontalAlignment = 'right';
                app.TensionDisturbanceNEditField(i).Position = [239 10 100 22];
                app.TensionDisturbanceNEditField(i).Value = '0';
            end
        end
        
        function results = simulateFucn(app)
            for i = 1:app.TheNumberofSpansEditField.Value
                E1eq = app.EquivalentYoungsModulusGPaEditField(i).Value * 10^9;
                E1t = app.InletYoungsModulusGPaEditField(i).Value * 10^9;
                ep1eq = app.ThermalStrainEditField(i).Value;
                t = app.WebThickness.Value / 10^6;
                w = app.WebWidth.Value / 1000;
                A = t*w;
                L1 = app.SpanLengthmEditField(i).Value;
                v = app.WebSpeed.Value;
                v00 = v;
                v10 = v;
                tau1 = L1 / v10;
                FT = app.FixedStep.Value;
                ST = app.StopTime.Value;
                set_param(app.simulModel, 'Solver', 'ode4', 'FixedStep', string(FT), 'StopTime', string(ST))

                simTime = 0:FT:ST;
%                 T0 = simTime;
%                 T0(:) = 0;
                if i == 1
                    T0 = [simTime ; makeDisturbance(app, app.TensionDisturbanceNEditField(i).Value, simTime, FT)]';
                else
                    T0 = [simTime ; results{i-1}.T1' + makeDisturbance(app, app.TensionDisturbanceNEditField(i).Value, simTime, FT)]';
                end
%                 V0 = simTime;
%                 V0(:) = 0;
                V0 = [simTime ; makeDisturbance(app, app.VelocityDisturbancemsEditField(i).Value, simTime, FT)]';
                
%                 V1 = simTime;
%                 V1(:) = 0;
                if i == app.TheNumberofSpansEditField.Value
                    V1 = [simTime ; simTime * 0]';
                else
                    V1 = [simTime ; makeDisturbance(app, app.VelocityDisturbancemsEditField(i+1).Value, simTime, FT)]';
                end

                assignin('base', 'E1eq', E1eq)
                assignin('base', 'E1t', E1t)
                assignin('base', 'ep1eq', ep1eq)
                assignin('base', 'A', A)
                assignin('base', 'L1', L1)
                assignin('base', 'v00', v00)
                assignin('base', 'v10', v10)
                assignin('base', 'tau1', tau1)
                assignin('base', 'T0', T0)
                assignin('base', 'V0', V0)
                assignin('base', 'V1', V1)
                results{i} = sim("Thermal_Tension_Transfer_Register.slx");
            end
        end
        
        function results = makeDisturbance(app, txt, simTime, FT)
            if txt == '0'
                results = simTime * 0;
            elseif findstr(txt, 'step')
                results = simTime * 0;
                results(10/FT+1:end) = str2num(txt(5:end));
            elseif findstr(txt, 'impulse')
                results = simTime * 0;
                results(10/FT+1:11/FT) = str2num(txt(8:end));
            elseif findstr(txt, 'sin')
                results = str2num(txt(4:end)) * sin(simTime);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.simulModel = load_system("Thermal_Tension_Transfer_Register.slx");
        end

        % Value changed function: TheNumberofSpansEditField
        function TheNumberofSpansEditFieldValueChanged(app, event)
            value = app.TheNumberofSpansEditField.Value;
            app.TheNumberofSpansSlider.Value = value;
            editSpan(app, value);
        end

        % Value changed function: TheNumberofSpansSlider
        function TheNumberofSpansSliderValueChanged(app, event)
            value = app.TheNumberofSpansSlider.Value;
            app.TheNumberofSpansEditField.Value = round(value);
            app.TheNumberofSpansSlider.Value = round(value);
            editSpan(app, round(value));
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            Results = simulateFucn(app);
            
            C = ["#000000", "#FF0000", "#0000FF", "#FF00FF", "#00FF00", "#0072BD", "#D95319", "#EDB120", "#7E2F8E", "#77AC30", "#4DBEEE", "#A2142F"];

            hold(app.TensionTransfer, 'off')
            plot(app.TensionTransfer, NaN, NaN)

            for i = 1:app.TheNumberofSpansEditField.Value
                plot(app.TensionTransfer, Results{i}.Time, app.OpTension.Value + Results{i}.T1, 'Color', C(i), 'LineWidth', 2)
                if i == 1
                    hold(app.TensionTransfer, 'on')
                end
                leg(i) = append("Span ", string(i));
            end
            lgd = legend(app.TensionTransfer, leg);
            if 7 < app.TheNumberofSpansEditField.Value
                lgd.NumColumns = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.TensionTransfer = uiaxes(app.Panel_2);
            title(app.TensionTransfer, 'Tension Transfer')
            xlabel(app.TensionTransfer, 'Time [sec]')
            ylabel(app.TensionTransfer, 'Tension [N]')
            zlabel(app.TensionTransfer, 'Z')
            app.RegisterError = uiaxes(app.Panel_2);
            title(app.RegisterError, 'MD Register Error')
            xlabel(app.RegisterError, 'Time [sec]')
            ylabel(app.RegisterError, 'MD Register [m]')
            zlabel(app.RegisterError, 'Z')
            app.SpanInfo = uiimage(app.UIFigure);
            app.SpanInfo.ImageSource = '1.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Span = uitab(app.TabGroup);
            app.Span.Title = 'Span1';
            app.InletYoungsModulusGPaEditFieldLabel = uilabel(app.Span);
            app.InletYoungsModulusGPaEditFieldLabel.Text = 'Inlet Young''s Modulus [GPa]';
            app.InletYoungsModulusGPaEditField = uieditfield(app.Span, 'numeric');
            app.InletYoungsModulusGPaEditField.Value = 1.883;
            app.EquivalentYoungsModulusGPaEditFieldLabel = uilabel(app.Span);
            app.EquivalentYoungsModulusGPaEditFieldLabel.Text = 'Equivalent Young''s Modulus [GPa]';
            app.EquivalentYoungsModulusGPaEditField = uieditfield(app.Span, 'numeric');
            app.EquivalentYoungsModulusGPaEditField.Value = 1.883;
            app.ThermalStrainEditFieldLabel = uilabel(app.Span);
            app.ThermalStrainEditFieldLabel.Text = 'Thermal Strain';
            app.ThermalStrainEditField = uieditfield(app.Span, 'numeric');
            app.SpanLengthmEditFieldLabel = uilabel(app.Span);
            app.SpanLengthmEditFieldLabel.Text = 'Span Length [m]';
            app.SpanLengthmEditField = uieditfield(app.Span, 'numeric');
            app.SpanLengthmEditField.Value = 3;
            app.VelocityDisturbancemsEditFieldLabel = uilabel(app.Span);
            app.VelocityDisturbancemsEditFieldLabel.Text = 'Velocity Disturbance [m/s]';
            app.VelocityDisturbancemsEditField = uieditfield(app.Span, 'text');
            app.VelocityDisturbancemsEditField.Value = '0';
            app.TensionDisturbanceNEditFieldLabel = uilabel(app.Span);
            app.TensionDisturbanceNEditFieldLabel.Text = 'Tension Disturbance [N]';
            app.TensionDisturbanceNEditField = uieditfield(app.Span, 'text');
            app.TensionDisturbanceNEditField.Value = '0';
            app.Panel = uipanel(app.UIFigure);
            app.TheNumberofSpansSlider = uislider(app.Panel);
            app.TheNumberofSpansSlider.Limits = [1 12];
            app.TheNumberofSpansSlider.MajorTicks = [1 4 7 10 12];
            app.TheNumberofSpansSlider.MajorTickLabels = {'1', '4', '7', '10', '12'};
            app.TheNumberofSpansSlider.ValueChangedFcn = createCallbackFcn(app, @TheNumberofSpansSliderValueChanged, true);
            app.TheNumberofSpansSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.TheNumberofSpansSlider.Value = 1;
            app.TheNumberofSpansEditFieldLabel = uilabel(app.Panel);
            app.TheNumberofSpansEditFieldLabel.Text = 'The Number of Spans';
            app.TheNumberofSpansEditField = uieditfield(app.Panel, 'numeric');
            app.TheNumberofSpansEditField.Limits = [1 12];
            app.TheNumberofSpansEditField.ValueChangedFcn = createCallbackFcn(app, @TheNumberofSpansEditFieldValueChanged, true);
            app.TheNumberofSpansEditField.Value = 1;
            app.FixedStepsecLabel = uilabel(app.Panel);
            app.FixedStepsecLabel.Text = 'Fixed Step [sec]';
            app.FixedStep = uieditfield(app.Panel, 'numeric');
            app.FixedStep.Value = 0.1;
            app.FixedStepsecLabel_2 = uilabel(app.Panel);
            app.FixedStepsecLabel_2.Text = 'Stop Time [sec]';
            app.StopTime = uieditfield(app.Panel, 'numeric');
            app.StopTime.Value = 1000;
            app.FixedStepsecLabel_3 = uilabel(app.Panel);
            app.FixedStepsecLabel_3.Text = 'Web Width [mm]';
            app.WebWidth = uieditfield(app.Panel, 'numeric');
            app.WebWidth.Value = 150;
            app.FixedStepsecLabel_4 = uilabel(app.Panel);
            app.FixedStepsecLabel_4.Text = 'Web Thickness [um]';
            app.WebThickness = uieditfield(app.Panel, 'numeric');
            app.WebThickness.Value = 100;
            app.FixedStepsecLabel_5 = uilabel(app.Panel);
            app.FixedStepsecLabel_5.Text = 'Web Speed [m/s]';
            app.WebSpeed = uieditfield(app.Panel, 'numeric');
            app.WebSpeed.Value = 0.016667;
            app.FixedStepsecLabel_6 = uilabel(app.Panel);
            app.FixedStepsecLabel_6.Text = 'Op. Tension [N]';
            app.OpTension = uieditfield(app.Panel, 'numeric');
            app.OpTension.Value = 26.487;
            app.KU = uiimage(app.UIFigure);
            app.KU.ImageSource = 'KU.jpg';
            app.SiM = uiimage(app.UIFigure);
            app.SiM.ImageSource = 'SiM.png';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = R2RContinuousProcessSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end